classdef zhezhou < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
        Button_5  matlab.ui.control.Button
        Button_6  matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
        UIAxes_2  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        samplepicture1;
        samplepicture2;% Description
         % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off');  %不显示坐标轴
            xlabel(app.UIAxes,'')  % 不显示X轴标签
            ylabel(app.UIAxes,'')   % 不显示Y轴标签
            
            axis(app.UIAxes_2,'off');  %不显示坐标轴
            xlabel(app.UIAxes_2,'')  % 不显示X轴标签
            ylabel(app.UIAxes_2,'')   % 不显示Y轴标签
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
[filename,pathname] = uigetfile({'*.jpg';'*.*'},'选择图片');
            if isequal(filename,0)||isequal(pathname,0)
                msgbox('您未选择图片','温馨提示','help');
                return;
            else
                x1=strcat(pathname,filename);    %连接路径名和文件名
                app.samplepicture1=imread(x1);
                imshow(app.samplepicture1,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
[filename,pathname] = uigetfile({'*.jpg';'*.*'},'选择图片');
            if isequal(filename,0)||isequal(pathname,0)
                msgbox('您未选择图片','温馨提示','help');
                return;
            else
                x2=strcat(pathname,filename);    %连接路径名和文件名
                app.samplepicture2=imread(x2);
                imshow(app.samplepicture2,'Parent',app.UIAxes_2);
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
%读取图片像素点数据
[m,n,~] = size(app.samplepicture1);
I2=im2double(app.samplepicture1);

thresh=graythresh(app.samplepicture1);      %获得较好的阈值
I3=im2bw(app.samplepicture1,thresh);        %转化成二值图
% imshow(app.samplepicture1);
% pause(0.2);


I4=I3;
w=bwmorph(I4,"thin",Inf);
% imshow(w);title("细化图")
% pause(0.2);     %用大图直观感受对比过程

d1=0;           %d1断点
c1=0;           %c1分叉点
   for i = 2 : m-1
        for j = 2: n-1
            if w(i,j)==1
  num=abs(w(i,j-1)-w(i-1,j-1))+abs(w(i+1,j-1)-w(i,j-1))+abs(w(i+1,j)-w(i+1,j-1))+abs(w(i+1,j+1)-w(i+1,j))+abs(w(i,j+1)-w(i+1,j+1))+abs(w(i-1,j+1)-w(i,j+1))+abs(w(i-1,j)-w(i-1,j+1))+abs(w(i-1,j-1)-w(i-1,j));
               if num==2
               d1=d1+1;
  app.samplepicture1(i,j)=255; app.samplepicture1(i-1,j-1)=255; app.samplepicture1(i,j-1)=255; app.samplepicture1(i+1,j-1)=255; app.samplepicture1(i+1,j)=255; app.samplepicture1(i+1,j+1)=255; app.samplepicture1(i,j+1)=255; app.samplepicture1(i-1,j+1)=255; app.samplepicture1(i-1,j)=255;
elseif num==6
    c1=c1+1;
                end
            end
        end
    end

display(d1)     %显示d1和c1
display(c1)
% imshow(app.samplepicture1)
% pause(0.2);

[m,n,~] = size(app.samplepicture2);
I6=im2double(app.samplepicture2);
thresh=graythresh(app.samplepicture2);
I7=im2bw(app.samplepicture2,thresh);
imshow(I7)
pause(0.2)
I8=I7;
u=bwmorph(I8,"thin",Inf);
imshow(u);title("细化图")
pause(0.2);
d2=0;
c2=0;
   for i = 2 : m-1
        for j = 2: n-1
            if u(i,j)==1
   num=abs(u(i,j-1)-u(i-1,j-1))+abs(u(i+1,j-1)-u(i,j-1))+abs(u(i+1,j)-u(i+1,j-1))+abs(u(i+1,j+1)-u(i+1,j))+abs(u(i,j+1)-u(i+1,j+1))+abs(u(i-1,j+1)-u(i,j+1))+abs(u(i-1,j)-u(i-1,j+1))+abs(u(i-1,j-1)-u(i-1,j));
                if num==2
               d2=d2+1;
  app.samplepicture2(i,j)=255;app.samplepicture2(i-1,j-1)=255;app.samplepicture2(i,j-1)=255;app.samplepicture2(i+1,j-1)=255;app.samplepicture2(i+1,j)=255;app.samplepicture2(i+1,j+1)=255;app.samplepicture2(i,j+1)=255;app.samplepicture2(i-1,j+1)=255;app.samplepicture2(i-1,j)=255;
elseif num==6
    c2=c2+1;
                end
            end
        end
    end

display(c2)
display(d2)
imshow(app.samplepicture2)
pause(0.2)

if abs((d1-d2)/d1)<0.05 && abs((c1-c2)/c1)<0.05
    imshow(app.samplepicture2,'Parent',app.UIAxes_2);
    msgbox('同一褶皱，匹配成功','匹配结果','help');
    t = 1;
else
    msgbox('匹配失败','匹配结果','help');
    t = 0;
end
  disp (t);

        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %delete(app.samplepicture1);
            delete(allchild(app.UIAxes));
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %delete(app.samplepicture2);
            delete(allchild(app.UIAxes_2));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '打开样品图片';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '输入对比图片';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '清除样品图片';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '清除对比图片';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '开始识别';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '退出';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '样品图片')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '对比图片')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zhezhou

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end